classdef App1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TabGroup               matlab.ui.container.TabGroup
        Tab                    matlab.ui.container.Tab
        LabelNumericEditField  matlab.ui.control.Label
        NumericEditField       matlab.ui.control.NumericEditField
        DropDown3              matlab.ui.control.DropDown
        LabelDropDown3         matlab.ui.control.Label
        UIAxes4                matlab.ui.control.UIAxes
        DropDown4              matlab.ui.control.DropDown
        LabelDropDown4         matlab.ui.control.Label
        UIAxes3                matlab.ui.control.UIAxes
        UIAxes2                matlab.ui.control.UIAxes
        DropDown               matlab.ui.control.DropDown
        LabelDropDown          matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
        DropDown2              matlab.ui.control.DropDown
        LabelDropDown2         matlab.ui.control.Label
        StateButton2           matlab.ui.control.StateButton
        StateButton3           matlab.ui.control.StateButton
        Tab2                   matlab.ui.container.Tab
        LabelEditField         matlab.ui.control.Label
        EditField              matlab.ui.control.EditField
        UIAxes5                matlab.ui.control.UIAxes
        UIAxes6                matlab.ui.control.UIAxes
        UIAxes7                matlab.ui.control.UIAxes
        UIAxes8                matlab.ui.control.UIAxes
        DropDown5              matlab.ui.control.DropDown
        Label                  matlab.ui.control.Label
        DropDown6              matlab.ui.control.DropDown
        Label2                 matlab.ui.control.Label
        DropDown7              matlab.ui.control.DropDown
        Label3                 matlab.ui.control.Label
        DropDown8              matlab.ui.control.DropDown
        Label4                 matlab.ui.control.Label
        Button                 matlab.ui.control.Button
    end


    methods (Access = public)
        
        function results = odczyt(app)
            disp(3)
                     tic


     t = 0;
y = 1;


toc
         disp(3)   
        end


        function results = run_com(app)
            disp(2)
            s2 = int2str(get(app.NumericEditField, 'Value'));
            s1 = 'COM';
            SerialPort = strcat(s1,s2);
           % s = serial(SerialPort, 'BaudRate', 57600);
            %fopen(s);
            while get(app.StateButton3, 'Value');
            odczyt(app)
            end
              %   fclose(s);
          %  delete(s);
          %  clear s;
          disp(2)
        end

    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Value changed function: StateButton2
        function StateButton2ValueChanged(app, event)
            while get(app.StateButton2, 'Value');
            disp(1)
            end
        end

        % Button pushed function: Button
        function ButtonButtonPushed(app, event)
            value1 = app.EditField.Value;
                       [o, ErrM] = fopen (value1, 'r');
        if(o == -1)
            warning(ErrM); 
            return;
        end;
        a = fscanf(o,'%f %f %f %f %f\n',[5, Inf])';
        b=[a(:,2),a(:,3),a(:,4),a(:,5)];
        d=[a(:,1)];
        fclose(o);
        serial_n1=061318011028;
        serial_n2=082918010321;
        serial_n3=102617010931;
        [m,n] = size(a);
        t_n1=1;
        t_n2=1;
        t_n3=1;
        tic
        for y=1:1:m
            if (a(y,1)==serial_n1)
               data_n1(t_n1,:)=[a(y,2),a(y,3),a(y,4),a(y,5)];
               t_n1=t_n1+1;
            end
             if (a(y,1)==serial_n2)
               data_n2(t_n2,:)=[a(y,2),a(y,3),a(y,4),a(y,5)];
               t_n2=t_n2+1;
             end
             if (a(y,1)==serial_n3)
               data_n3(t_n3,:)=[a(y,2),a(y,3),a(y,4),a(y,5)];
               t_n3=t_n3+1;
            end
        end
        toc
        
        switch (app.DropDown7.Value)
    case 'CO'
        x=data_n1(:,4);
        y1=data_n1(:,1);
        plot(app.UIAxes5,x,y1,'r-');
    case 'H2S'
        x=data_n2(:,4);
        y1=data_n2(:,1);
        plot(app.UIAxes5,x,y1,'r-');
            case 'SO2'
        x=data_n3(:,4);
        y1=data_n3(:,1);
        plot(app.UIAxes5,x,y1,'r-');
        end
        
        end

        % Value changed function: StateButton3
        function StateButton3ValueChanged(app, event)
            value = app.StateButton3.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Real_com_data';
            app.LabelNumericEditField = uilabel(app.Tab);
            app.LabelNumericEditField.Text = 'COM:';
            app.NumericEditField = uieditfield(app.Tab, 'numeric');
            app.DropDown3 = uidropdown(app.Tab);
            app.DropDown3.Items = {'CO', 'H2S', 'SO2', 'O3', 'NO2'};
            app.DropDown3.Value = 'CO';
            app.LabelDropDown3 = uilabel(app.Tab);
            app.LabelDropDown3.Text = 'DGS';
            app.UIAxes4 = uiaxes(app.Tab);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 't [s]')
            ylabel(app.UIAxes4, 'Y')
            app.DropDown4 = uidropdown(app.Tab);
            app.DropDown4.Items = {'temperature', 'humidity', 'pressure'};
            app.DropDown4.Value = 'temperature';
            app.LabelDropDown4 = uilabel(app.Tab);
            app.LabelDropDown4.Text = 'BME280';
            app.UIAxes3 = uiaxes(app.Tab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 't [s]')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes2 = uiaxes(app.Tab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 't [s]')
            ylabel(app.UIAxes2, 'Y')
            app.DropDown = uidropdown(app.Tab);
            app.DropDown.Items = {'Compass', 'Accelerometer', 'Rotation'};
            app.DropDown.Value = 'Compass';
            app.LabelDropDown = uilabel(app.Tab);
            app.LabelDropDown.Text = 'AltIMU-10';
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 't [s]')
            ylabel(app.UIAxes, 'Y')
            app.DropDown2 = uidropdown(app.Tab);
            app.LabelDropDown2 = uilabel(app.Tab);
            app.LabelDropDown2.Text = 'Drop Down';
            app.StateButton2 = uibutton(app.Tab, 'state');
            app.StateButton2.ValueChangedFcn = createCallbackFcn(app, @StateButton2ValueChanged, true);
            app.StateButton2.Text = 'run com';
            app.StateButton3 = uibutton(app.Tab, 'state');
            app.StateButton3.ValueChangedFcn = createCallbackFcn(app, @StateButton3ValueChanged, true);
            app.StateButton3.Text = 'Plot';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'plot_data';
            app.LabelEditField = uilabel(app.Tab2);
            app.LabelEditField.Text = 'filename';
            app.EditField = uieditfield(app.Tab2, 'text');
            app.UIAxes5 = uiaxes(app.Tab2);
            title(app.UIAxes5, 'PPB of gas')
            xlabel(app.UIAxes5, 't [s]')
            ylabel(app.UIAxes5, 'Concentration')
            app.UIAxes6 = uiaxes(app.Tab2);
            title(app.UIAxes6, 'Title')
            xlabel(app.UIAxes6, 't [s]')
            ylabel(app.UIAxes6, 'Y')
            app.UIAxes7 = uiaxes(app.Tab2);
            title(app.UIAxes7, 'Title')
            xlabel(app.UIAxes7, 't [s]')
            ylabel(app.UIAxes7, 'Y')
            app.UIAxes8 = uiaxes(app.Tab2);
            title(app.UIAxes8, 'Title')
            xlabel(app.UIAxes8, 't [s]')
            ylabel(app.UIAxes8, 'Y')
            app.DropDown5 = uidropdown(app.Tab2);
            app.DropDown5.Items = {'Compass', 'Accelerometer', 'Rotation'};
            app.DropDown5.Value = 'Compass';
            app.Label = uilabel(app.Tab2);
            app.Label.Text = 'AltIMU-10';
            app.DropDown6 = uidropdown(app.Tab2);
            app.Label2 = uilabel(app.Tab2);
            app.Label2.Text = 'Drop Down';
            app.DropDown7 = uidropdown(app.Tab2);
            app.DropDown7.Items = {'CO', 'H2S', 'SO2', 'O3', 'NO2'};
            app.DropDown7.Value = 'CO';
            app.Label3 = uilabel(app.Tab2);
            app.Label3.Text = 'DGS';
            app.DropDown8 = uidropdown(app.Tab2);
            app.DropDown8.Items = {'temperature', 'humidity', 'pressure'};
            app.DropDown8.Value = 'temperature';
            app.Label4 = uilabel(app.Tab2);
            app.Label4.Text = 'BME280';
            app.Button = uibutton(app.Tab2, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonButtonPushed, true);
            app.Button.Text = 'plot';
        end
    end

    methods (Access = public)

        % Construct app
        function app = App1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end